classdef MotorHealth < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MotorDataUIFigure      matlab.ui.Figure
        GridLayout             matlab.ui.container.GridLayout
        LeftPanel              matlab.ui.container.Panel
        MotorDataListBoxLabel  matlab.ui.control.Label
        MotorDataListBox       matlab.ui.control.ListBox
        VisualizeButton        matlab.ui.control.Button
        MotorDataFolderButton  matlab.ui.control.Button
        ClearButton            matlab.ui.control.Button
        RightPanel             matlab.ui.container.Panel
        HelpLabel              matlab.ui.control.Label
        MotorLabel             matlab.ui.control.Label
        UIAxes                 matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        MotorDataFolder % Description
        AppName = "Motor Data";
        PersistCtrl
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            ylim(app.UIAxes,[0, 4500]);
            plot(app.UIAxes,NaT,NaN);  % Hack to set datetimeruler x-axis.
            app.MotorDataUIFigure.Name = app.AppName;
            
        end

        % Button pushed function: MotorDataFolderButton
        function MotorDataFolderButtonPushed(app, event)
            app.MotorDataFolder = uigetdir("Motor Data Folder");
            figure(app.MotorDataUIFigure); % Restore focus after uigetfile.
            app.MotorDataUIFigure.Name = sprintf('%s: %s', ...
                app.AppName, app.MotorDataFolder);
            d = dir(fullfile(app.MotorDataFolder,'*.mat'));
            
            % A stupid amount of work to put the MotorData files in numeric
            % order.
            names = {d.name};
            % 1. Cell array of the numeric part of each name. Each cell is
            % holds a cell containing a 1x1 string of the numeric part.
            t = regexp(names,'([0-9]+)[.]mat','tokens');
            
            % 2. Strip away the inner cell arrays -- n is a cell array of
            % 1x1 strings.
            n = cellfun(@(e)e{1},t,'UniformOutput',false);
            
            % 3. Concatenate all the 1x1 strings into a 1xN string, and
            % convert each string to a double. motorN is a 1xN double.
            motorN = str2double(vertcat(n{:}));
            
            % 4. Numeric sort -- only care about the sorting transform.
            [~,I] = sort(motorN);
            
            % 5. Apply the sorting transform to the original list of names
            % and Bob's your uncle. (Why is that good?)
            names = names(I);
            
            app.MotorDataListBox.Items = names;
            app.MotorDataListBox.Enable = true;
            app.MotorDataListBoxLabel.Enable = true;
        end

        % Button pushed function: VisualizeButton
        function VisualizeButtonPushed(app, event)
            file = app.MotorDataListBox.Value;
  
            m = regexp(file,'([0-9]+)[.]mat','tokenExtents');
            key = [];
            if ~isempty(m)
                e = m{1};    % First token
                key = str2double(file(e(1):e(2)));
            end
            app.HelpLabel.Visible = false;
            app.MotorLabel.Text = sprintf("Motor %d", key);
            app.MotorLabel.Visible = true;
            cla(app.UIAxes);
            app.UIAxes.Visible = true;
            file = fullfile(app.MotorDataFolder,file);
            validateModels(file,key,app.UIAxes);
            app.ClearButton.Enable = true;
           
        end

        % Value changed function: MotorDataListBox
        function MotorDataListBoxValueChanged(app, event)
            value = app.MotorDataListBox.Value;
            app.VisualizeButton.Enable = true;
        end

        % Button pushed function: ClearButton
        function ClearButtonPushed(app, event)
             cla(app.UIAxes);
             app.MotorLabel.Visible = false;
             legend(app.UIAxes,'off');
             app.ClearButton.Enable = false;
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.MotorDataUIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {220, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.MotorDataUIFigure = uifigure('Visible', 'off');
            app.MotorDataUIFigure.Name = 'Motor Data';
            app.MotorDataUIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.MotorDataUIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.MotorDataListBoxLabel = uilabel(app.LeftPanel);
            app.MotorDataListBoxLabel.Enable = 'off';
            app.MotorDataListBoxLabel.Text = {'Motor'; 'Data'};
            app.MotorDataListBox = uilistbox(app.LeftPanel);
            app.MotorDataListBox.Items = {'Select Folder...'};
            app.MotorDataListBox.ValueChangedFcn = createCallbackFcn(app, @MotorDataListBoxValueChanged, true);
            app.MotorDataListBox.Enable = 'off';
            app.MotorDataListBox.Value = 'Select Folder...';
            app.VisualizeButton = uibutton(app.LeftPanel, 'push');
            app.VisualizeButton.ButtonPushedFcn = createCallbackFcn(app, @VisualizeButtonPushed, true);
            app.VisualizeButton.Enable = 'off';
            app.VisualizeButton.Text = 'Visualize';
            app.MotorDataFolderButton = uibutton(app.LeftPanel, 'push');
            app.MotorDataFolderButton.ButtonPushedFcn = createCallbackFcn(app, @MotorDataFolderButtonPushed, true);
            app.MotorDataFolderButton.Text = 'Motor Data Folder...';
            app.ClearButton = uibutton(app.LeftPanel, 'push');
            app.ClearButton.ButtonPushedFcn = createCallbackFcn(app, @ClearButtonPushed, true);
            app.ClearButton.Enable = 'off';
            app.ClearButton.Text = 'Clear';
            app.RightPanel = uipanel(app.GridLayout);
            app.HelpLabel = uilabel(app.RightPanel);
            app.HelpLabel.Text = 'Select a motor data file and click Visualize';
            app.MotorLabel = uilabel(app.RightPanel);
            app.MotorLabel.Visible = 'off';
            app.UIAxes = uiaxes(app.RightPanel);
            title(app.UIAxes, 'Predictive Maintenance Results')
            xlabel(app.UIAxes, 'Sensor Report Time (Once Per Minute)')
            ylabel(app.UIAxes, 'Remining Useful Life (Minutes)')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.Visible = 'off';
            % Show the figure after all components are created
            app.MotorDataUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MotorHealth

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MotorDataUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MotorDataUIFigure)
        end
    end
end